classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        TexteButton                    matlab.ui.control.Button
        ImageButton                    matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        Image3_3                       matlab.ui.control.Image
        CalculateButton                matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        Tree                           matlab.ui.container.Tree
        HuffmanDictionnaryNode         matlab.ui.container.TreeNode
        Tree_2                         matlab.ui.container.Tree
        ShannonFanoDictionnaryNode     matlab.ui.container.TreeNode
        CopyrightGSEIIENSAFESBougrineImaneLabel  matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        Taux_HuffmanEditFieldLabel     matlab.ui.control.Label
        Taux_HuffmanEditField          matlab.ui.control.EditField
        Taux_ShannonEditFieldLabel     matlab.ui.control.Label
        Taux_ShannonEditField          matlab.ui.control.EditField
        LongueurmoyenneEditFieldLabel  matlab.ui.control.Label
        LongueurmoyenneEditField       matlab.ui.control.EditField
        LongueurmoyenneEditField_2Label  matlab.ui.control.Label
        LongueurmoyenneEditField_2     matlab.ui.control.EditField
        EntrerletexteEditFieldLabel    matlab.ui.control.Label
        EntrerletexteEditField         matlab.ui.control.EditField
        Panel_3                        matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        MatriceTab                     matlab.ui.container.Tab
        Image3                         matlab.ui.control.Image
        CalculerButton_2               matlab.ui.control.Button
        EditField_2                    matlab.ui.control.EditField
        tailleEditFieldLabel           matlab.ui.control.Label
        tailleEditField                matlab.ui.control.NumericEditField
        MatrixTextAreaLabel            matlab.ui.control.Label
        MatrixTextArea                 matlab.ui.control.TextArea
        HorizontaleEditField_2Label    matlab.ui.control.Label
        HorizontaleEditField_2         matlab.ui.control.EditField
        ZigzagEditField_3Label         matlab.ui.control.Label
        ZigzagEditField_3              matlab.ui.control.EditField
        VerticaleEditField_2Label      matlab.ui.control.Label
        VerticaleEditField_2           matlab.ui.control.EditField
        Image4_2                       matlab.ui.control.Image
        CopyrightGSEIIENSAFESBougrineImaneLabel_2  matlab.ui.control.Label
        ImageTab                       matlab.ui.container.Tab
        Image3_2                       matlab.ui.control.Image
        InsrerlaphotoButton            matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        CalculerButton_3               matlab.ui.control.Button
        EditField_3                    matlab.ui.control.EditField
        VerticaleEditField_3Label      matlab.ui.control.Label
        VerticaleEditField_3           matlab.ui.control.EditField
        ZigzagEditField_4Label         matlab.ui.control.Label
        ZigzagEditField_4              matlab.ui.control.EditField
        HorizontaleEditField_3Label    matlab.ui.control.Label
        HorizontaleEditField_3         matlab.ui.control.EditField
        Image4                         matlab.ui.control.Image
        CopyrightGSEIIENSAFESBougrineImaneLabel_3  matlab.ui.control.Label
    end

    
    properties (Access = private)
        p; % Description
        matrix;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)
            app.EntrerletexteEditField.Value="Hello world";
        end

        % Button pushed function: TexteButton
        function TexteButtonPushed(app, event)
             app.Panel.Visible='off';
             app.Panel_2.Visible='on';
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
           delete(app.HuffmanDictionnaryNode.Children)
           delete(app.ShannonFanoDictionnaryNode.Children)
           if(app.EntrerletexteEditField.Value~="")
                %app.Label_2.Text=strcat(app.Label_2.Text,' calculating');
                message=app.EntrerletexteEditField.Value;
            %huffman
            S=unique(message);
            y = zeros(1,length(S));
            for i=1:length(unique(message))
                c=strfind(message,S(i));
                y(i)=length(c);
            end
            Pr=y./length(message);
            [huffman_code,L]=huffmandict(double(S),Pr);%donne l'arbre de codage
            %display huffman dict
            for i=1:length(huffman_code)
                node=uitreenode(app.HuffmanDictionnaryNode);
                node.Text = sprintf('%c => %s',char(huffman_code{i,1}),num2str(huffman_code{i,2})); 
            end
            SIG=message;
            huffman_code2=huffmanenco(SIG,huffman_code); %correspondance entre le message et le code generé par 
            taux_compression=1-(length(huffman_code2)/(length(SIG)*8));
            huffman_code3=huffmandeco(huffman_code2,huffman_code);
            app.Taux_HuffmanEditField.Value= sprintf('%f',taux_compression);
            app.LongueurmoyenneEditField_2.Value=sprintf('%f',L);
            %shannon -Fano
            fano_code=ShannonFanoCode(double(S),Pr);
            fano_code2=EntropyEncoder(message,fano_code);
            len=0;
            for i=1:length(fano_code)
                node1=uitreenode(app.ShannonFanoDictionnaryNode);
                node1.Text = sprintf('%c => %s',char(fano_code{i,1}),num2str(fano_code{i,2})); 
            end
            for i=1:length(fano_code2)
                len=len+length(fano_code2{i});
                 
            end
            taux_compression2=1-(len/(length(message)*8));
            app.Taux_ShannonEditField.Value= sprintf('%f',taux_compression2);
            %calcul de la longueur moyenne
            long=0;
            for i=1:length(fano_code)
                long=long+Pr(i)*length(fano_code{i,2});
            end
            app.LongueurmoyenneEditField.Value=sprintf('%f',long);
            %app.Label_2.Text=strcat(app.Label_2.Text,' done');
            %conclusion
                if(taux_compression>taux_compression2)
                    app.Label.Text='Dans ce cas, le codage Huffman est plus optimal que celui de shannon-Fano ';    
                elseif(taux_compression<taux_compression2)
                     %meme si ce cas n'est pas possible   
                    app.Label.Text='Dans ce cas, le codage Shannon-Fano est plus optimal que celui de Huffman '; 
                else
                    app.Label.Text='Dans ce cas, le codage Huffman et Shannon-Fano sont équivalents'; 
                end
           else
               app.Label_2.Text='please enter a text'; 
               app.Label_2.FontColor='red';
           end
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
             app.Panel.Visible='on';
             app.Panel_2.Visible='off';
             app.Panel_3.Visible='off';
            
        end

        % Button pushed function: InsrerlaphotoButton
        function InsrerlaphotoButtonPushed2(app, event)
            [filename ,pathname]=uigetfile({'*.jpg ; *.png',},'choisir une image');
            %fullpath=strcat(filename,pathname);
            
            app.p=imread([pathname filename]);
            if(size(app.p,1)>300 && size(app.p,2)>300)
                app.p=imresize(app.p,[300,300]);
            else
                app.p=imresize(app.p,[min(size(app.p)),min(size(app.p))]);
            end
            imshow(app.p,'Parent',app.UIAxes);
        end

        % Button pushed function: CalculerButton_3
        function CalculerButtonPushed(app, event)
            M=app.p;
            if(size(M,3)==3)
              M=rgb2gray(M);  
            end
            [n,m]=size(M)
            T=M';
            flat_H=M(:)';
            flat_V=T(:)';
            zig=zigzag(M);
            val=[scan(flat_H,n,m),scan(zig,n,m),scan(flat_V,n,m)];
            app.HorizontaleEditField_3.Value=sprintf('%d',val(1));
            app.VerticaleEditField_3.Value=sprintf('%d',val(3));
            app.ZigzagEditField_4.Value=sprintf('%d',val(2));
            if(val(1)==val(2)&& val(2)==val(3) && val(3)==0)
                app.EditField_3.Value="choisissez une image grayscale pour avoir plus de zeros";
            else
                if(max(val)==val(1) && val(1)~=val(2) && val(1)~=val(3))
                    app.EditField_3.Value="la lecture horizontale est la meilleure";
                elseif(max(val)==val(2) && val(2)~=val(1) && val(2)~=val(3))
                    app.EditField_3.Value="la lecture zigzag est la meilleure";
                elseif(max(val)==val(3) && val(3)~=val(2) && val(3)~=val(1))
                    app.EditField_3.Value="la lecture verticale est la meilleure";
                elseif(max(val)==val(1) && val(1)==val(2))
                    app.EditField_3.Value="les lectures horizontale et zigzag sont les meilleures";
                elseif(max(val)==val(1) && val(1)==val(3))
                    app.EditField_3.Value="les lectures horizontale et Verticale sont les meilleures";
                elseif(max(val)==val(3) && val(1)==val(2))
                    app.EditField_3.Value="les lectures verticale et zigzag sont les meilleures";
                end
            end
        end

        % Button pushed function: CalculerButton_2
        function CalculerButton_2Pushed(app, event)
            if(app.tailleEditField.Value == 0 )
                app.EditField_2.Value="la taille de la matrice doit différente de 0";
            else
                %app.matrix=sprintf('%s',app.MatrixTextArea.Value)
                app.matrix=str2num(char(app.MatrixTextArea.Value));
                %app.matrix=app.MatrixTextArea.Value;
                if(isempty(app.matrix))
                    app.EditField_2.Value="matrice sous forme [0 1 ; 2 6] par exemple";
                else
                    [n,m]=size(app.matrix)
                    
                    if(n~=app.tailleEditField.Value || m~=app.tailleEditField.Value)
                        app.EditField_2.Value="taille incorrecte";
                    else
                        M=app.matrix;
                        T=M';
                        flat_H=M(:)';
                        flat_V=T(:)';
                        zig=zigzag(M);
                        val=[scan(flat_H,n,m),scan(zig,n,m),scan(flat_V,n,m)];
                        app.HorizontaleEditField_2.Value=sprintf('%d',val(1));
                        app.VerticaleEditField_2.Value=sprintf('%d',val(3));
                        app.ZigzagEditField_3.Value=sprintf('%d',val(2));
                        if(val(1)==val(2)&& val(2)==val(3) && val(3)==0)
                            app.EditField_2.Value="choisissez une image grayscale pour avoir plus de zeros";
                        else
                            if(max(val)==val(1) && val(1)~=val(2) && val(1)~=val(3))
                                app.EditField_2.Value="la lecture horizontale est la meilleure";
                            elseif(max(val)==val(2) && val(2)~=val(1) && val(2)~=val(3))
                                app.EditField_2.Value="la lecture zigzag est la meilleure";
                            elseif(max(val)==val(3) && val(3)~=val(2) && val(3)~=val(1))
                                app.EditField_2.Value="la lecture verticale est la meilleure";
                            elseif(max(val)==val(1) && val(1)==val(2))
                                app.EditField_2.Value="les lectures horizontale et zigzag sont les meilleures";
                            elseif(max(val)==val(1) && val(1)==val(3))
                                app.EditField_2.Value="les lectures horizontale et Verticale sont les meilleures";
                            elseif(max(val)==val(3) && val(1)==val(2))
                                app.EditField_2.Value="les lectures verticale et zigzag sont les meilleures";
                            end
                        end
                    end
                end
                
            end
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            app.Panel.Visible='on';
             app.Panel_2.Visible='off';
             app.Panel_3.Visible='off';
        end

        % Image clicked function: Image4_2
        function Image4_2Clicked(app, event)
            app.Panel.Visible='on';
             app.Panel_2.Visible='off';
             app.Panel_3.Visible='off';
        end

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
            app.Panel.Visible='off';
             app.Panel_2.Visible='off';
             app.Panel_3.Visible='on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'background.jpg';
            app.TexteButton = uibutton(app.Panel, 'push');
            app.TexteButton.ButtonPushedFcn = createCallbackFcn(app, @TexteButtonPushed, true);
            app.TexteButton.Text = 'Texte';
            app.ImageButton = uibutton(app.Panel, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Text = 'Image';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Visible = 'off';
            app.Image3_3 = uiimage(app.Panel_2);
            app.Image3_3.ImageSource = 'bg.png';
            app.CalculateButton = uibutton(app.Panel_2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '';
            app.Tree = uitree(app.Panel_2);
            app.HuffmanDictionnaryNode = uitreenode(app.Tree);
            app.HuffmanDictionnaryNode.Text = 'Huffman Dictionnary';
            app.Tree_2 = uitree(app.Panel_2);
            app.ShannonFanoDictionnaryNode = uitreenode(app.Tree_2);
            app.ShannonFanoDictionnaryNode.Text = 'Shannon-Fano Dictionnary';
            app.CopyrightGSEIIENSAFESBougrineImaneLabel = uilabel(app.Panel_2);
            app.CopyrightGSEIIENSAFESBougrineImaneLabel.Text = 'Copyright © : GSEII ENSA FES - Bougrine Imane';
            app.Image2 = uiimage(app.Panel_2);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'icon.png';
            app.Taux_HuffmanEditFieldLabel = uilabel(app.Panel_2);
            app.Taux_HuffmanEditFieldLabel.Text = 'Taux_Huffman';
            app.Taux_HuffmanEditField = uieditfield(app.Panel_2, 'text');
            app.Taux_ShannonEditFieldLabel = uilabel(app.Panel_2);
            app.Taux_ShannonEditFieldLabel.Text = 'Taux_Shannon';
            app.Taux_ShannonEditField = uieditfield(app.Panel_2, 'text');
            app.LongueurmoyenneEditFieldLabel = uilabel(app.Panel_2);
            app.LongueurmoyenneEditFieldLabel.Text = 'Longueur moyenne';
            app.LongueurmoyenneEditField = uieditfield(app.Panel_2, 'text');
            app.LongueurmoyenneEditField_2Label = uilabel(app.Panel_2);
            app.LongueurmoyenneEditField_2Label.Text = 'Longueur moyenne';
            app.LongueurmoyenneEditField_2 = uieditfield(app.Panel_2, 'text');
            app.EntrerletexteEditFieldLabel = uilabel(app.Panel_2);
            app.EntrerletexteEditFieldLabel.Text = 'Entrer le texte';
            app.EntrerletexteEditField = uieditfield(app.Panel_2, 'text');
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Visible = 'off';
            app.TabGroup = uitabgroup(app.Panel_3);
            app.MatriceTab = uitab(app.TabGroup);
            app.MatriceTab.Title = 'Matrice';
            app.Image3 = uiimage(app.MatriceTab);
            app.Image3.ImageSource = 'bg.png';
            app.CalculerButton_2 = uibutton(app.MatriceTab, 'push');
            app.CalculerButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculerButton_2Pushed, true);
            app.CalculerButton_2.Text = 'Calculer';
            app.EditField_2 = uieditfield(app.MatriceTab, 'text');
            app.tailleEditFieldLabel = uilabel(app.MatriceTab);
            app.tailleEditFieldLabel.Text = 'taille';
            app.tailleEditField = uieditfield(app.MatriceTab, 'numeric');
            app.MatrixTextAreaLabel = uilabel(app.MatriceTab);
            app.MatrixTextAreaLabel.Text = 'Matrix';
            app.MatrixTextArea = uitextarea(app.MatriceTab);
            app.HorizontaleEditField_2Label = uilabel(app.MatriceTab);
            app.HorizontaleEditField_2Label.Text = 'Horizontale';
            app.HorizontaleEditField_2 = uieditfield(app.MatriceTab, 'text');
            app.ZigzagEditField_3Label = uilabel(app.MatriceTab);
            app.ZigzagEditField_3Label.Text = 'Zigzag';
            app.ZigzagEditField_3 = uieditfield(app.MatriceTab, 'text');
            app.VerticaleEditField_2Label = uilabel(app.MatriceTab);
            app.VerticaleEditField_2Label.Text = 'Verticale';
            app.VerticaleEditField_2 = uieditfield(app.MatriceTab, 'text');
            app.Image4_2 = uiimage(app.MatriceTab);
            app.Image4_2.ImageClickedFcn = createCallbackFcn(app, @Image4_2Clicked, true);
            app.Image4_2.ImageSource = 'icon.png';
            app.CopyrightGSEIIENSAFESBougrineImaneLabel_2 = uilabel(app.MatriceTab);
            app.CopyrightGSEIIENSAFESBougrineImaneLabel_2.Text = 'Copyright © : GSEII ENSA FES - Bougrine Imane';
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.Image3_2 = uiimage(app.ImageTab);
            app.Image3_2.ImageSource = 'bg.png';
            app.InsrerlaphotoButton = uibutton(app.ImageTab, 'push');
            app.InsrerlaphotoButton.ButtonPushedFcn = createCallbackFcn(app, @InsrerlaphotoButtonPushed2, true);
            app.InsrerlaphotoButton.Text = 'Insérer la photo';
            app.UIAxes = uiaxes(app.ImageTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.CalculerButton_3 = uibutton(app.ImageTab, 'push');
            app.CalculerButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculerButtonPushed, true);
            app.CalculerButton_3.Text = 'Calculer';
            app.EditField_3 = uieditfield(app.ImageTab, 'text');
            app.VerticaleEditField_3Label = uilabel(app.ImageTab);
            app.VerticaleEditField_3Label.Text = 'Verticale';
            app.VerticaleEditField_3 = uieditfield(app.ImageTab, 'text');
            app.ZigzagEditField_4Label = uilabel(app.ImageTab);
            app.ZigzagEditField_4Label.Text = 'Zigzag';
            app.ZigzagEditField_4 = uieditfield(app.ImageTab, 'text');
            app.HorizontaleEditField_3Label = uilabel(app.ImageTab);
            app.HorizontaleEditField_3Label.Text = 'Horizontale';
            app.HorizontaleEditField_3 = uieditfield(app.ImageTab, 'text');
            app.Image4 = uiimage(app.ImageTab);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = 'icon.png';
            app.CopyrightGSEIIENSAFESBougrineImaneLabel_3 = uilabel(app.ImageTab);
            app.CopyrightGSEIIENSAFESBougrineImaneLabel_3.Text = 'Copyright © : GSEII ENSA FES - Bougrine Imane';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end